classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ViewMeshButton                 matlab.ui.control.StateButton
        RestartSwordButton             matlab.ui.control.Button
        MovementSpeedEvaluateBotGaugeLabel  matlab.ui.control.Label
        IgnitionEbotSwitchLabel        matlab.ui.control.Label
        IgnitionQBotSwitchLabel        matlab.ui.control.Label
        MovementSpeedEvaluateBotGauge  matlab.ui.control.LinearGauge
        MovementSpeedQuenchBotGaugeLabel  matlab.ui.control.Label
        IgnitionEbotSwitch             matlab.ui.control.ToggleSwitch
        IgnitionQBotSwitch             matlab.ui.control.ToggleSwitch
        EmergencyButton                matlab.ui.control.StateButton
        WSlider                        matlab.ui.control.Slider
        WSliderLabel                   matlab.ui.control.Label
        MovementSpeedQuenchBotGauge    matlab.ui.control.LinearGauge
        StartProgramButton             matlab.ui.control.Button
        VSlider                        matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        OperatingLamp                  matlab.ui.control.Lamp
        OperatingLampLabel             matlab.ui.control.Label
        USlider                        matlab.ui.control.Slider
        USliderLabel                   matlab.ui.control.Label
        IdleLamp                       matlab.ui.control.Lamp
        IdleLampLabel                  matlab.ui.control.Label
        ProgramSelectButtonGroup       matlab.ui.container.ButtonGroup
        DisposeButton                  matlab.ui.control.ToggleButton
        EvaluateButton                 matlab.ui.control.ToggleButton
        QuenchButton                   matlab.ui.control.ToggleButton
        PickupButton                   matlab.ui.control.ToggleButton
        MainButton                     matlab.ui.control.ToggleButton
        ZSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        StoppedLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        AxisControlButtonGroup         matlab.ui.container.ButtonGroup
        EvaluateBotButton              matlab.ui.control.RadioButton
        QuenchBotButton                matlab.ui.control.RadioButton
        XSlider                        matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        CurrentRobot  % Stores the currently selected robot (Excalibot or UR5)
        robot_excalibot %Excalibot class handler
        robot_UR %UR robot class handler
        robot_UR_maxLimits
        robot_UR_minLimits
        robot_excalibot_maxLimits
        robot_excalibot_minLimits
        gripper
        environment

        selectedProgram
        programRunning
        holdingSword
        robotMoving

        camera
        tempFigureHandle
        tempAxesHandle
        updateCamera
        cameraOffset

        shpExcalibotplot 
        accurateShpExcalibotPlot 
        shpURplot 
        accurateShpURplot

        logfilename
        serialObj
    end

    methods (Access = public)

        % Fetch current robot position and update sliders
        function updateSliders(app, robot)
            % Get the current position and rotation of the robot's end effector
            currentPosition = transl(robot.model.fkine(robot.model.getpos()));  % Get translation (x, y, z)
            currentRPY = tr2rpy(robot.model.fkine(robot.model.getpos()));       % Get rotation (roll, pitch, yaw)

            % Define limits for each robot

            if robot == app.robot_excalibot
                minLimits = [-1.9650, -1.3, 0.1];
                maxLimits = [1.2, 1.1, 2.3165];
            elseif robot == app.robot_UR
                minLimits = [-2.2, -1.3, 0.1];
                maxLimits = [-0.3786, 0.3258, 1.6089];
            end

            % Set slider limits based on robot type
            app.XSlider.Limits = [minLimits(1), maxLimits(1)];
            app.YSlider.Limits = [minLimits(2), maxLimits(2)];
            app.ZSlider.Limits = [minLimits(3), maxLimits(3)];

            % Ensure current position values are within the limits
            xValue = min(max(currentPosition(1), minLimits(1)), maxLimits(1));
            yValue = min(max(currentPosition(2), minLimits(2)), maxLimits(2));
            zValue = min(max(currentPosition(3), minLimits(3)), maxLimits(3));

            % Update sliders with constrained position values
            app.XSlider.Value = xValue;  % X-axis
            app.YSlider.Value = yValue;  % Y-axis
            app.ZSlider.Value = zValue;  % Z-axis

            % Convert RPY angles from radians to degrees and constrain them
            rollValue = min(max(rad2deg(currentRPY(1)), -180), 180);
            pitchValue = min(max(rad2deg(currentRPY(2)), -180), 180);
            yawValue = min(max(rad2deg(currentRPY(3)), -180), 180);

            % Update sliders with constrained RPY values
            app.USlider.Value = rollValue;  % Roll
            app.VSlider.Value = pitchValue; % Pitch
            app.WSlider.Value = yawValue;   % Yaw
        end

        % Function to move the robot based on slider values
        function moveRobotBasedOnSliders(app, robot)
            if (~app.robotMoving) && (~app.programRunning)
                % Fetch current slider values for position (X, Y, Z) and rotation (U, V, W)
                newPosition = [app.XSlider.Value, app.YSlider.Value, app.ZSlider.Value];
                newRotation = deg2rad([app.USlider.Value, app.VSlider.Value, app.WSlider.Value]);

                logEntry(app, app.logfilename, sprintf('Desired Position Based On Slider: %s', mat2str([newPosition,newRotation])));

                moveRobot(app, robot, newPosition, newRotation, 50);
            end
        end

        function moveRobot(app, robot, desiredPosition, desiredRotation, steps)
            app.robotMoving = true;

            checkForBoundries = true;
            graphTrajectory = true;
            graphBoundryBox = true;

            if robot == app.robot_excalibot
                ignition = app.IgnitionQBotSwitch;
                app.updateCamera = false;
            elseif robot == app.robot_UR
                ignition = app.IgnitionEbotSwitch;
                if ignition.Value == "On" && app.EmergencyButton.Value == false
                    app.updateCamera = true;
                    if isvalid(app.tempAxesHandle) % Only delete if it is valid
                        delete(app.tempAxesHandle);
                    end
                    app.tempAxesHandle = copyobj(app.environment.envAxes, app.tempFigureHandle);
                end
            end

            if ignition.Value == "On" && app.EmergencyButton.Value == false
                [finalJointAngles, finalPosition, finalRotation, positionError, rotationError,withinBounds]  = calculateMotion(app, robot, desiredPosition, desiredRotation);
                logEntry(app, app.logfilename, sprintf('Desired Position: %s', mat2str(desiredPosition)));
                logEntry(app, app.logfilename, sprintf('Final Pose: %s', mat2str([finalPosition,finalRotation])));
                logEntry(app, app.logfilename, sprintf('Position Error: %s', mat2str(positionError)));
                
                trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox,withinBounds);
                logEntry(app, app.logfilename, sprintf('Moving Robot to: %s', mat2str([finalPosition,finalRotation])));
                animateMotion(app, robot, trajectory, ignition);
            else
                logEntry(app, app.logfilename, sprintf('Movement Could not proceed: %s Ignition value: %s Estop: %s', mat2str(desiredPosition), ignition.Value, mat2str(app.EmergencyButton.Value)));
            end

            %Ending Operation
            updateSliders(app, robot);
            updateLights(app);

            app.robotMoving = false;
            app.updateCamera = false;  
            
        end

        function [jointAngles, finalPosition, finalRotation, positionError, rotationError, withinBounds] = calculateMotion(app, robot, desiredPosition, desiredRotation)
            if desiredPosition(2) < 1.15 && desiredPosition(1) < 1.25 && desiredPosition(2) > -1.35 && desiredPosition(1) > -2.25 && desiredPosition(3) > 0.15
                withinBounds = true;
                [isColliding, ~]=app.environment.checkPointCollision(desiredPosition);
                if isColliding
                    logEntry(app, app.logfilename, sprintf('Desired Position Colliding: %s', mat2str(desiredPosition)));
                end
            else
                withinBounds = false;
                logEntry(app, app.logfilename, sprintf('Desired Position Out of Bounds: %s', mat2str(desiredPosition)));
            end
            % Compute the initial transformation matrix from the desired position and rotation
            newTransformation = transl(desiredPosition) * rpy2tr(desiredRotation);

            % Inverse kinematics with tolerance options
            jointAngles = robot.model.ikcon(newTransformation, 'TolFun', 1e-6, 'TolX', 1e-6);

            % Optionally refine the solution using the IK result
            jointAngles = robot.model.ikcon(newTransformation, jointAngles);

            % Forward kinematics to calculate the resulting end-effector pose
            endEffectorPose = robot.model.fkine(jointAngles);

            % Extract final position and rotation
            finalPosition = transl(endEffectorPose);
            finalRotation = tr2rpy(endEffectorPose);

            % Calculate position error
            positionError = finalPosition - desiredPosition;

            % Calculate rotational error in quaternion form
            endEffectorMatrix = endEffectorPose.T;  % Extract the 4x4 homogeneous transformation matrix
            rotationMatrix = endEffectorMatrix(1:3, 1:3);

            % Convert the rotation matrix to quaternion
            finalQuat = rotm2quat(rotationMatrix);
            desiredQuat = eul2quat(desiredRotation);

            % Compute the quaternion inverse manually
            desiredQuatInv = [desiredQuat(1), -desiredQuat(2), -desiredQuat(3), -desiredQuat(4)];

            % Calculate quaternion multiplication (finalQuat * desiredQuatInv)
            rotationError = quatmultiplyManual(app, finalQuat, desiredQuatInv);
        end

        function trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox, withinBounds)
            % Create Trajectory for the movemement
            accel_time = 0.3;     % Acceleration time (portion of trajectory for accelerating)
            peakVel = 0.5;

            % Create a time profile with trapezoidal velocity profile
            timeProfile = trapveltraj([0, 1], steps,"AccelTime",accel_time,"PeakVelocity",peakVel);

            trajectory = jtraj(robot.model.getpos(),finalJointAngles, timeProfile);
            
            xMin = -2.2; xMax = 1.2;
            yMin = -1.3; yMax = 1.1;
            zMin = 0.1;  % No upper bound for z

            if checkForBoundries == true
                if robot == app.robot_excalibot

                    %Clearance and offset for excalibot
                    endEffector_clearance_x = 0.2;
                    endEffector_clearance_y = 0.2;
                    endEffector_clearance_z = 0.15;

                    endEffector_offset_x = -0.01;
                    endEffector_offset_y = -0.05;
                    endEffector_offset_z = 0.1;

                    joint_clearance_x = 0.2;
                    joint_clearance_y = 0.2;
                    joint_clearance_z = 0.1;

                    joint_offset_x = 0;
                    joint_offset_y = -0.05;
                    joint_offset_z = 0.2;

                elseif robot == app.robot_UR

                    %Clearance and offset for UR5
                    endEffector_clearance_x = 0.05;
                    endEffector_clearance_y = 0.05;
                    endEffector_clearance_z = 0.03;

                    endEffector_offset_x = 0.01;
                    endEffector_offset_y = 0.01;
                    endEffector_offset_z = 0;

                    joint_clearance_x = 0.09;
                    joint_clearance_y = 0.09;
                    joint_clearance_z = 0.03;

                    joint_offset_x = 0;
                    joint_offset_y = 0;
                    joint_offset_z = 0.05;
                end

                % Calculate all end effector positions
                % Initialize a cell array to store end-effector poses (transformation matrices)
                endEffectorPoses = cell(size(trajectory, 1), 1);
                endEffectorPositions = cell(size(trajectory, 1), 1);
                endEffectorRotations = cell(size(trajectory, 1), 1);

                % Initialize arrays to store joint positions throughout the trajectory
                numJoints = length(robot.model.links);
                jointPositions = zeros(size(trajectory, 1), numJoints + 1, 3); % [step, joint+base, x/y/z]

                for i = 1:size(trajectory, 1)
                    % get current joint state
                    q = trajectory(i, :);

                    % Extrack coordinates of end effector
                    endEffectorPose = robot.model.fkine(q);
                    endEffectorPosition = transl(endEffectorPose);
                    endEffectorRotation = tr2rpy(endEffectorPose);

                    if withinBounds
                        if ~(endEffectorPosition(2) < 1.1 && endEffectorPosition(1) < 1.2 && endEffectorPosition(2) > -1.3 && endEffectorPosition(1) > -2.2 && endEffectorPosition(3) > 0.1)
                            endEffectorPosition(1) = max(min(endEffectorPosition(1), xMax), xMin);
                            endEffectorPosition(2) = max(min(endEffectorPosition(2), yMax), yMin);
                            endEffectorPosition(3) = max(endEffectorPosition(3), zMin);  % Only lower bound for z
                            
                            newTransformation = transl(endEffectorPosition) * rpy2tr(endEffectorRotation);
                           
                            jointAngles = robot.model.ikcon(newTransformation);

                            trajectory(i, :) = jointAngles;
                        else
                            [isColliding, closestPoint]=app.environment.checkPointCollision(endEffectorPosition);
                            if isColliding
                                newTransformation = transl(closestPoint) * rpy2tr(endEffectorRotation);
                                jointAngles = robot.model.ikcon(newTransformation);
                                trajectory(i, :) = jointAngles;
                            end
                        end

                    end

                    % Calculate the transformation matrix for each joint at step i
                    T = robot.model.base; % Start with the base transformation
                    jointPositions(i, 1, :) = T.t; % Store base position

                    for j = 1:numJoints
                        T = T * robot.model.A(j, q); % Multiply by the transformation of each joint
                        jointPositions(i, j+1, :) = T.t; % Store the position of the joint
                    end


                    % save the coordinates of end effector
                    endEffectorPoses{i} = endEffectorPose;
                    endEffectorPositions{i} = endEffectorPosition;
                    endEffectorRotations{i} = endEffectorRotation;
                end

                % Extract end effector x, y, z
                xPos = zeros(size(endEffectorPositions, 1), 1);
                yPos = zeros(size(endEffectorPositions, 1), 1);
                zPos = zeros(size(endEffectorPositions, 1), 1);
                for i = 1:size(endEffectorPositions, 1)
                    position = endEffectorPositions(i);
                    xyzPos = position{1};
                    xPos(i) = xyzPos(1);
                    yPos(i) = xyzPos(2);
                    zPos(i) = xyzPos(3);
                end

                % Find the minimum and maximum values for x, y, and z end effector positions
                minX_endEffector = (min(xPos) - endEffector_clearance_x) + endEffector_offset_x;
                maxX_endEffector = (max(xPos) + endEffector_clearance_x) + endEffector_offset_x;

                minY_endEffector = (min(yPos) - endEffector_clearance_y) + endEffector_offset_y;
                maxY_endEffector = (max(yPos) + endEffector_clearance_y) + endEffector_offset_y;

                minZ_endEffector = (min(zPos) - endEffector_clearance_z) + endEffector_offset_z;
                maxZ_endEffector = (max(zPos) + endEffector_clearance_z) + endEffector_offset_z;

                % find the minimum and maximum values for x, y, and z for
                % total robot
                minX_total = (min(jointPositions(:, :, 1), [], 'all') - joint_clearance_x) + joint_offset_x;
                maxX_total = (max(jointPositions(:, :, 1), [], 'all') + joint_clearance_x) + joint_offset_x;

                minY_total = (min(jointPositions(:, :, 2), [], 'all') - joint_clearance_y) + joint_offset_y;
                maxY_total = (max(jointPositions(:, :, 2), [], 'all') + joint_clearance_y) + joint_offset_y;

                minZ_total = (min(jointPositions(:, :, 3), [], 'all') - joint_clearance_z) + joint_offset_z;
                maxZ_total = (max(jointPositions(:, :, 3), [], 'all') + joint_clearance_z) + joint_offset_z;

                % Define persistent variables to store plot handles
                persistent trajHandle boxHandles totalBoundryHandles

                % Check and delete the previous trajectory and bounding box if they exist
                if ~isempty(trajHandle)
                    delete(trajHandle);  % Delete the old trajectory plot
                end
                if ~isempty(boxHandles)
                    delete(boxHandles);  % Delete the old end effector bounding box plot (both corners and edges)
                end
                if ~isempty(totalBoundryHandles)
                    delete(totalBoundryHandles);  % Delete the old total bounding box plot (both corners and edges)
                end

                % Plot the new bounding box if needed
                if graphBoundryBox == true

                    % Define the 8 corners of the total bounding box
                    corners_total = [
                        minX_total, minY_total, minZ_total;  % Corner 1 (min x, min y, min z)
                        maxX_total, minY_total, minZ_total;  % Corner 2 (max x, min y, min z)
                        maxX_total, maxY_total, minZ_total;  % Corner 3 (max x, max y, min z)
                        minX_total, maxY_total, minZ_total;  % Corner 4 (min x, max y, min z)
                        minX_total, minY_total, maxZ_total;  % Corner 5 (min x, min y, max z)
                        maxX_total, minY_total, maxZ_total;  % Corner 6 (max x, min y, max z)
                        maxX_total, maxY_total, maxZ_total;  % Corner 7 (max x, max y, max z)
                        minX_total, maxY_total, maxZ_total   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the 8 corners of the end effector bounding box
                    corners_endEffector = [
                        minX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 1 (min x, min y, min z)
                        maxX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 2 (max x, min y, min z)
                        maxX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 3 (max x, max y, min z)
                        minX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 4 (min x, max y, min z)
                        minX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 5 (min x, min y, max z)
                        maxX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 6 (max x, min y, max z)
                        maxX_endEffector, maxY_endEffector, maxZ_endEffector;  % Corner 7 (max x, max y, max z)
                        minX_endEffector, maxY_endEffector, maxZ_endEffector   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the edges of the box (each row corresponds to a pair of corners to connect)
                    edges = [
                        1, 2;  % Connect corner 1 to 2
                        2, 3;  % Connect corner 2 to 3
                        3, 4;  % Connect corner 3 to 4
                        4, 1;  % Connect corner 4 to 1 (base of the box)
                        5, 6;  % Connect corner 5 to 6
                        6, 7;  % Connect corner 6 to 7
                        7, 8;  % Connect corner 7 to 8
                        8, 5;  % Connect corner 8 to 5 (top of the box)
                        1, 5;  % Connect corner 1 to 5
                        2, 6;  % Connect corner 2 to 6
                        3, 7;  % Connect corner 3 to 7
                        4, 8   % Connect corner 4 to 8
                        ];


                    % Plot the corners of the total bounding box
                    totalBoundryHandles = gobjects(size(corners_total, 1) + size(edges, 1), 1);  % Preallocate handles
                    totalBoundryHandles(1:size(corners_total, 1)) = plot3(corners_total(:, 1), corners_total(:, 2), corners_total(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'y','Parent',app.environment.envAxes);  % Red markers for corners
                    hold on;

                    % Plot the edges of the total box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_total(edges(i, 1), :);  % First point of the edge
                        p2 = corners_total(edges(i, 2), :);  % Second point of the edge
                        totalBoundryHandles(size(corners_total, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'y', 'LineWidth', 2,'Parent',app.environment.envAxes);  % Plot the edge as a blue line
                    end

                    % Plot the corners of the end effector bounding box
                    boxHandles = gobjects(size(corners_endEffector, 1) + size(edges, 1), 1);  % Preallocate handles
                    boxHandles(1:size(corners_endEffector, 1)) = plot3(corners_endEffector(:, 1), corners_endEffector(:, 2), corners_endEffector(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'b','Parent',app.environment.envAxes);  % Red markers for corners
                    hold on;

                    % Plot the edges of the end effector box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_endEffector(edges(i, 1), :);  % First point of the edge
                        p2 = corners_endEffector(edges(i, 2), :);  % Second point of the edge
                        boxHandles(size(corners_endEffector, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'b', 'LineWidth', 2,'Parent',app.environment.envAxes);  % Plot the edge as a blue line
                    end
                end

                % Plot the new trajectory if needed
                if graphTrajectory == true
                    % Plot the new trajectory and store its handle
                    for i = 1:length(yPos)

                        [isColliding, ~]=app.environment.checkPointCollision([xPos(i), yPos(i), zPos(i)]);
                        if yPos(i) <= 1.15 && xPos(i) <= 1.25 && yPos(i) >= -1.35 && xPos(i) >= -2.25 && zPos(i) >= 0.05 && ~(isColliding)
                            
                            % Plot points with y < 1.2 in green
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'go', 'LineWidth', 2, 'MarkerSize', 5,'Parent',app.environment.envAxes)];
                        else
                            % Plot points with y >= 1.2 in red
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'ro', 'LineWidth', 2, 'MarkerSize', 5,'Parent',app.environment.envAxes)];
                        end
                    end
                    hold on;
                end
            end
        end

        function animateMotion(app, robot, trajectory, ignition)
            % Enable operating lamp and disable idle/stopped lamps
            app.OperatingLamp.Enable = "on";
            app.IdleLamp.Enable = "off";
            app.StoppedLamp.Enable = "off";
            swordTracking = false;

            % Update the appropriate speed gauge based on robot type
            if robot == app.robot_UR
                gauge = app.MovementSpeedEvaluateBotGauge;
            elseif robot == app.robot_excalibot
                gauge = app.MovementSpeedQuenchBotGauge;
                if app.holdingSword
                    swordTracking = true;
                end
            end

            % Initialize previous position
            previousPosition = transl(robot.model.fkine(trajectory(1, :)));

            % Animate motion
            for i = 1:size(trajectory, 1)
                % Get current end-effector position
                endEffectorPose = robot.model.fkine(trajectory(i, :));
                endEffectorPosition = transl(endEffectorPose);
                if i + 10 <= size(trajectory, 1)
                futureEFPose = robot.model.fkine(trajectory(i+10, :));
                futureEFPosition = transl(futureEFPose);
                else
                    pointsLeft = size(trajectory, 1) - i;
                    futureEFPose = robot.model.fkine(trajectory(i+pointsLeft, :));
                    futureEFPosition = transl(futureEFPose);
                end
            
                [isColliding, ~]=app.environment.checkPointCollision(futureEFPosition);
                % Check boundary and ignition conditions
                if ~(endEffectorPosition(2) < 1.15 && endEffectorPosition(1) < 1.25 && endEffectorPosition(2) > -1.35 && endEffectorPosition(1) > -2.25 && endEffectorPosition(3) > 0.15)
                    logEntry(app, app.logfilename, sprintf('Movement Stopped (Out Of Bounds): %s Ignition value: %s Estop: %s', mat2str(endEffectorPosition), ignition.Value, mat2str(app.EmergencyButton.Value)));
                    break;
                end
                if ~(ignition.Value == "On" && app.EmergencyButton.Value == false)
                    logEntry(app, app.logfilename, sprintf('Movement Stopped (Estop or Ignition): %s Ignition value: %s Estop: %s', mat2str(endEffectorPosition), ignition.Value, mat2str(app.EmergencyButton.Value)));
                    break;
                end
                if isColliding
                    logEntry(app, app.logfilename, sprintf('Movement Stopped (Collision): %s', mat2str(endEffectorPosition)));
                    break;
                end

                % Calculate speed (distance between current and previous position)
                speed = (norm(endEffectorPosition - previousPosition))*1000;

                gauge.Value = speed;


                % Animate robot and update sliders
                robot.model.animate(trajectory(i, :));

                if swordTracking
                    robot.UpdateSwordMesh(app.environment.sword.patch,app.environment.sword.vertices);
                end
                if app.updateCamera

                    T = endEffectorPose.T;
                    R = T(1:3, 1:3);
                    app.camera = app.camera.setPosition(endEffectorPosition+(R * app.cameraOffset)'); 
                    app.camera = app.camera.setOrientation(R*rotx(deg2rad(90))*roty(deg2rad(0))*rotz(deg2rad(0)));

                    app.camera.captureAndDisplay(app.tempAxesHandle,app.environment.envAxes);
                end
                

                updateSliders(app, robot);
                drawnow();

                % Update previous position for next iteration
                previousPosition = endEffectorPosition;
            end

            % Final update of sliders and draw
            updateSliders(app, robot);

            % Update the appropriate speed gauge based on robot type
            gauge.Value = 0;

            drawnow();
        end

        function [reachablePoints, accuratePoints, minXYZ, maxXYZ, accurateMinXYZ, accurateMaxXYZ] = findReachablePoints(app, robot, xRange, yRange, zRange, resolution)
            % Initialize arrays to store reachable points
            reachablePoints = [];
            accuratePoints = [];

            % Create a mesh grid of points in the specified ranges
            [X, Y, Z] = meshgrid(xRange(1):resolution:xRange(2), ...
                yRange(1):resolution:yRange(2), ...
                zRange(1):resolution:zRange(2));

            % Iterate through the grid of points
            for i = 1:numel(X)
                % Define current desired position
                desiredPosition = [X(i), Y(i), Z(i)];
                logEntry(app, app.logfilename, sprintf('Desired Position:%s', mat2str(desiredPosition)));

                % Set the desired rotation to zero (neutral orientation), you can modify this as needed
                desiredRotation = [0, 0, 0];  % roll, pitch, yaw


                [jointAngles, finalPosition, finalRotation, positionError, rotationError,withinBounds] = calculateMotion(app, robot, desiredPosition, desiredRotation);

                % If the position error is small, consider the point reachable
                reachablePoints = [reachablePoints; finalPosition];  % Add the reachable point

                if positionError < 0.01
                    accuratePoints = [accuratePoints; finalPosition];  % Add the reachable point
                end


            end

            % Calculate the minimum and maximum x, y, z coordinates of the reachable points
            if ~isempty(reachablePoints)
                minXYZ = min(reachablePoints, [], 1);  % [minX, minY, minZ]
                maxXYZ = max(reachablePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                minXYZ = [];
                maxXYZ = [];
            end

            if ~isempty(accuratePoints)
                accurateMinXYZ = min(accuratePoints, [], 1);  % [minX, minY, minZ]
                accurateMaxXYZ = max(accuratePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                accurateMinXYZ = [];
                accurateMaxXYZ = [];
            end
        end

        function result = quatmultiplyManual(app, q1, q2)
            % Quaternion multiplication: q1 * q2
            w1 = q1(1); x1 = q1(2); y1 = q1(3); z1 = q1(4);
            w2 = q2(1); x2 = q2(2); y2 = q2(3); z2 = q2(4);

            % Quaternion multiplication formula
            result = [
                w1*w2 - x1*x2 - y1*y2 - z1*z2, ...
                w1*x2 + x1*w2 + y1*z2 - z1*y2, ...
                w1*y2 + y1*w2 + z1*x2 - x1*z2, ...
                w1*z2 + z1*w2 + x1*y2 - y1*x2
                ];
        end

        function MoveRobotToSword(app)
            if app.IgnitionQBotSwitch.Value == "On" && app.EmergencyButton.Value == false
                app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
                logEntry(app, app.logfilename, "Selected Robot QuenchBot");
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);

                % Define waypoints for the robot
                first_point = [1.121, 0.52165, 0.82457, 0, 0, 0];  % Above the sword
                second_point = [1.121, 0.52165, 0.7, 0, 0, 0];  % Down to the sword
                third_point = first_point;  % Back up to the first point
                fourth_point = [0.836, -0.16782, 0.9, 0, 0, 0];  % Move to another location
                fifth_point = [0.836, -0.65, 0.9, 0, 0, 0];  % Final location

                % Move to the first point (above the sword)
                logEntry(app, app.logfilename, 'Moving to the first point (above the sword)...');
                moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);

                % Move down to the second point (on the sword)
                logEntry(app, app.logfilename, 'Moving down to the second point (on the sword)...');
                moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);

                pause(0.5);  % Wait for 1 second to simulate gripping the sword
                app.holdingSword = true;
                logEntry(app, app.logfilename, sprintf('Sword Picked Up: %s', mat2str(second_point)));

                % Enable sword tracking and move back up
                logEntry(app, app.logfilename, 'Gripping the sword and moving back up...');
                moveRobot(app,app.robot_excalibot,third_point(1:3),third_point(4:6),100);

                % Move to the third point
                logEntry(app, app.logfilename, 'Moving to the third point... (Outside the kiln)');
                moveRobot(app,app.robot_excalibot,fourth_point(1:3),fourth_point(4:6),100);

                % Move to the final point
                logEntry(app, app.logfilename, 'Moving to the final point...(Into the kiln)');
                moveRobot(app,app.robot_excalibot,fifth_point(1:3),fifth_point(4:6),100);            
                
                logEntry(app, app.logfilename, 'Sword Heating Up');
            else
                logEntry(app, app.logfilename, sprintf('Program Could not Proceed: %s Ignition value: %s Estop: %s', "Pickup", app.IgnitionQBotSwitch.Value, mat2str(app.EmergencyButton.Value)));
            end
        end

        function MoveToWater(app)
            if app.IgnitionQBotSwitch.Value == "On" && app.EmergencyButton.Value == false
                app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
                logEntry(app, app.logfilename, "Selected Robot QuenchBot");
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);

                % Define waypoints for the robot
                first_point = [0.836, 0.1, 0.9, 0, 0, 0];  % First point (above the water bucket)
                rotating_point1 = [0, -0.8, 1.5,  1.57, 0, 1.57];
                fourth_point = [0, -0.8, 0.7, 1.57, 0, 1.57];   % Final position in the water bucket

                % Move to the first point (above the water bucket)
                logEntry(app, app.logfilename, 'Moving to the first point (Take the sword from kiln)...');
                moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);                

                % Move to the third point (final position in the water bucket)
                logEntry(app, app.logfilename, 'Moving down to the second point (above the water bucket)...');
                moveRobot(app,app.robot_excalibot,rotating_point1(1:3),rotating_point1(4:6),100);

                % Move to the third point (final position in the water bucket)
                logEntry(app, app.logfilename, 'Moving to the third point (in the water bucket)...');
                moveRobot(app,app.robot_excalibot,fourth_point(1:3),fourth_point(4:6),100);
            else
                logEntry(app, app.logfilename, sprintf('Program Could not Proceed: %s Ignition value: %s Estop: %s', "Quench", app.IgnitionQBotSwitch.Value, mat2str(app.EmergencyButton.Value)));
            end
        end

        function MoveToInspection(app)
            if app.IgnitionQBotSwitch.Value == "On" && app.EmergencyButton.Value == false
                app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
                logEntry(app, app.logfilename, "Selected Robot QuenchBot");
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);

                % Define waypoints for the robot
                first_point = [0, -0.8, 1.5, 1.57, -0, 1.57];
                second_point =  [-0.75, -0.459, 0.831, 0, -0, 0];


                % Move to the first point (above the water bucket)
                logEntry(app, app.logfilename, 'Moving to the first point (above the water bucket)...');
                moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);            % Wait for half a second

                % Move down to the second point (near the water bucket)
                logEntry(app, app.logfilename, 'Moving down to the second point (ready for scan)...');
                moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
            else
                logEntry(app, app.logfilename, sprintf('Program Could not Proceed: %s Ignition value: %s Estop: %s', "Evaluate", app.IgnitionQBotSwitch.Value, mat2str(app.EmergencyButton.Value)));
            end
        end

        function MoveCOBOTtoInspection(app)
            if app.IgnitionEbotSwitch.Value == "On" && app.EmergencyButton.Value == false
                app.AxisControlButtonGroup.SelectedObject = app.EvaluateBotButton;
                logEntry(app, app.logfilename, "Selected Robot EvaluateBot");
                updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
                app.CurrentRobot = app.robot_UR;  % Store the selected robot
                updateLights(app);

                % Define the waypoints for the robot in 3D space
                second_point = [-0.75, -0.62, 0.941, 0, pi, 0];  % First point in space (intermediate)
                third_point = [-0.76, -1.05, 0.941, 0, pi, 0];   % Second point in space (final)
                endscan_point = [-1.7, -0.5, 1.4, 0, 0, 0];

                logEntry(app, app.logfilename, 'Moving to the first point with downward orientation...');
                moveRobot(app,app.robot_UR,second_point(1:3),second_point(4:6),100);

                logEntry(app, app.logfilename, 'Slowly moving to the second point with downward orientation...');
                moveRobot(app,app.robot_UR,third_point(1:3),third_point(4:6),100);

                logEntry(app, app.logfilename, 'Returning to the original position...');
                moveRobot(app,app.robot_UR,endscan_point(1:3),endscan_point(4:6),100);

                logEntry(app, app.logfilename, 'Returned to the original position.');
            else
                logEntry(app, app.logfilename, sprintf('Program Could not Proceed: %s Ignition value: %s Estop: %s', "Evaluate", app.IgnitionEbotSwitch.Value, mat2str(app.EmergencyButton.Value)));
            end
        end

        function MoveToDropOff(app)
            if app.IgnitionQBotSwitch.Value == "On" && app.EmergencyButton.Value == false
                app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
                logEntry(app, app.logfilename, "Selected Robot QuenchBot");
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);

                % Define waypoints for the robot
                first_point = [-1.1456, 0.98152, 0.9, 0, 0, 0];
                second_point =  [-1.1456, 0.98152, 0.7, 0, 0, -pi/2];
                Final_point =  [0.7474, 0.2926, 1.2722, 0, 0, 0];


                % Move to the first point
                logEntry(app, app.logfilename, 'Moving to the dropoff...');
                moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);
                % Wait for half a second

                % Move down to the second point (near
                logEntry(app, app.logfilename, 'dropping off');
                moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
                app.holdingSword = false;
                logEntry(app, app.logfilename, sprintf('Sword Dropped Off: %s', mat2str(second_point)));
                moveRobot(app,app.robot_excalibot,Final_point(1:3),Final_point(4:6),100);
            else
                logEntry(app, app.logfilename, sprintf('Program Could not Proceed: %s Ignition value: %s Estop: %s', "Dispose", app.IgnitionQBotSwitch.Value, mat2str(app.EmergencyButton.Value)));
            end
        end

        function MainProgram(app)

            MoveRobotToSword(app);

            MoveToWater(app);

            MoveToInspection(app);

            MoveCOBOTtoInspection(app);

            MoveToDropOff(app);
        end
    end

    methods (Access = private)

        function updateLights(app)
            if app.EmergencyButton.Value == true
                app.OperatingLamp.Enable = "off";
                app.IdleLamp.Enable = "off";
                app.StoppedLamp.Enable ="on";
            else
                %Adjust Lamps QBot
                if app.IgnitionQBotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionQBotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
                %Adjust Lamps EBot
                if app.IgnitionEbotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionEbotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
            end
        end

        function createBoundryShapes(app)

            %%%% Find reachable points for robots %%%%%
            % Define the workspace boundaries and resolution
            xRange = [-5, 5];  % X limits in meters
            yRange = [-5, 5];  % Y limits in meters
            zRange = [0.1, 5];   % Z limits in meters
            resolution = 0.2;  % 10 cm resolution

            % Call the function to find reachable Exclaibot points
            [reachablePointsExcalibot,accuratePointsExcalibot, app.robot_excalibot_minLimits, app.robot_excalibot_maxLimits,accurateMinXYZexcalibot,accurateMaxXYZexcalibot] = findReachablePoints(app, app.robot_excalibot, xRange, yRange, zRange, resolution);

            if isempty(reachablePointsExcalibot)
                disp('No reachable points to plot.');
                return;
            end

            % Use alphaShape to create a mesh representing the volume of reachable points
            shpExcalibot = alphaShape(reachablePointsExcalibot(:,1), reachablePointsExcalibot(:,2), reachablePointsExcalibot(:,3), resolution); % 1 is the alpha radius, adjust as needed
            accurateShpExcalibot = alphaShape(accuratePointsExcalibot(:,1), accuratePointsExcalibot(:,2), accuratePointsExcalibot(:,3), resolution);

            % Plot the alpha shape
            shpExcalibotplot = plot(shpExcalibot);
            accurateShpExcalibotPlot = plot(accurateShpExcalibot);
            % Set the color and transparency properties
            shpExcalibotplot.FaceColor = [0.8, 0.4, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            shpExcalibotplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            shpExcalibotplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            % Set the color and transparency properties
            accurateShpExcalibotPlot.FaceColor = 'red';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            accurateShpExcalibotPlot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            accurateShpExcalibotPlot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)


            xRange = [-5, 5];  % X limits in meters
            yRange = [-5, 5];  % Y limits in meters
            zRange = [0.1, 5];   % Z limits in meters
            resolution = 0.2;  % 5 cm resolution

            % Call the function to find reachable URponts
            [reachablePointsUR,accuratePointsUR, app.robot_UR_minLimits, app.robot_UR_maxLimits,accurateMinXYZur,accurateMaxXYZur] = findReachablePoints(app, app.robot_UR, xRange, yRange, zRange, resolution);

            if isempty(reachablePointsUR)
                disp('No reachable points to plot.');
                return;
            end

            % Use alphaShape to create a mesh representing the volume of reachable points
            shpUR = alphaShape(reachablePointsUR(:,1), reachablePointsUR(:,2), reachablePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed
            accurateShpUR = alphaShape(accuratePointsUR(:,1), accuratePointsUR(:,2), accuratePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed

            % Plot the alpha shape
            shpURplot = plot(shpUR);
            accurateShpURplot = plot(accurateShpUR);
            % Set the color and transparency properties
            shpURplot.FaceColor = [0.4, 0.7, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            shpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            shpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            % Set the color and transparency properties
            accurateShpURplot.FaceColor = 'green';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            accurateShpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            accurateShpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            disp("MinExcalibot");
            disp(app.robot_excalibot_minLimits);

            disp ("MaxExcalibot");
            disp(app.robot_excalibot_maxLimits);

            disp("AccurateMinExcalibot");
            disp(accurateMinXYZexcalibot);

            disp ("AccurateMaxExcalibot");
            disp(accurateMaxXYZexcalibot);

            disp("MinUR");
            disp(app.robot_UR_minLimits);

            disp ("MaxUR");
            disp(app.robot_UR_maxLimits);

            disp("AccurateMinUR");
            disp(accurateMinXYZur);

            disp ("AccurateMaxUR");
            disp(accurateMaxXYZur);

            save("reachablePointsExcalibot_far.mat","reachablePointsExcalibot");
            save("reachablePointsUR_far.mat","reachablePointsUR");
            save("AccuratePointsExcalibot_far.mat","accuratePointsExcalibot");
            save("AccuratePointsUR_far.mat","accuratePointsUR");

        end

        function filename = createLogFile(app,folderPath)
            % Check if the folder path exists; if not, create it
            if ~exist(folderPath, 'dir')
                mkdir(folderPath);
            end

            % Get the current date and time to create a unique filename
            timestamp = datetime('now', 'Format', 'dd_MM_yy_HHmmss');
            filename = fullfile(folderPath, sprintf('log%s.txt', char(timestamp)));

            % Create the file and close it to initialize
            fileID = fopen(filename, 'w');
            if fileID == -1
                error('Could not create log file in the specified folder.');
            end
            fclose(fileID);

            % Inform the user
            fprintf('Log file created: %s\n', filename);
        end

        function logEntry(app, filename, logMessage)
            % Open the existing log file in append mode
            fileID = fopen(filename, 'a');
            if fileID == -1
                error('Could not open log file.');
            end

            % Write the log message with a timestamp
            fprintf(fileID, '[%s] %s\n', datestr(now, 'dd/mm/yy HH:MM:SS'), logMessage);

            % Close the file to save changes
            fclose(fileID);

            % Inform the user
            % fprintf('Log entry added to %s\n', filename);
        end

        function serialCallback(app, src, ~)
            % Read data from the Arduino
            data = readline(src);

            % Display or log the data (optional, for debugging)
            logEntry(app, app.logfilename, "Recieved Serial Communication");

            % Trigger specific event or behavior based on received data
            if strcmp(strtrim(data), "Pressed")
                logEntry(app, app.logfilename, "Hardware Estop Button activated");
                app.EmergencyButton.Value = ~app.EmergencyButton.Value;
                logEntry(app, app.logfilename, sprintf('EmergencyButtonValueChanged: %s', mat2str(app.EmergencyButton.Value)));
                updateLights(app);
            end
            if strcmp(strtrim(data), "LightCurtainStopped")
                logEntry(app, app.logfilename, "Light Curtain Safe, Ready to Deactivate Estop");
            end
            if strcmp(strtrim(data), "LightCurtainTriggered")
                app.EmergencyButton.Value = true;
                logEntry(app, app.logfilename, "Light Curtain Triggered, Estop Button activated");
                logEntry(app, app.logfilename, sprintf('EmergencyButtonValueChanged: %s', mat2str(app.EmergencyButton.Value)));
                updateLights(app);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%% System Start    %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            startup_rvc;
            clc; close all;

            app.logfilename = createLogFile(app, "log_Files");
            logEntry(app, app.logfilename, "Session started");

            logEntry(app, app.logfilename, "Establishing Serial Connection");

            try
                % Replace 'COM3' with your Arduino’s port and 9600 with your baud rate
                app.serialObj = serialport('/dev/tty.usbserial-1110', 9600);

                % Configure callback to be triggered on receiving data
                configureCallback(app.serialObj, "terminator", @app.serialCallback);

                logEntry(app, app.logfilename, "Serial Connection Established");
            catch ME
                logEntry(app, app.logfilename, sprintf("Failed to Establish Serial Connection:%s", ME.message));
                app.serialObj = [];
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%   Initialize Environment  %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.environment = Environment;
            logEntry(app, app.logfilename, "Environment Initialised");
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%  Excalibot   %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position Excalibot%%%%%%%%%
            % translation part
            position_Excalibot = [-0.06748, 0.3819, 0]; % x, y, z coordinates

            % rotation part
            roll_Excalibot = deg2rad(0); % Roll (rotation about x-axis)
            pitch_Excalibot = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_Excalibot = deg2rad(180); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_Excalibot = transl(position_Excalibot) * rpy2tr(roll_Excalibot, pitch_Excalibot, yaw_Excalibot);

            %%%%%%%%%% Create an instance of the Excalibot class %%%%%%%%
            app.robot_excalibot = Excalibot(baseTr_Excalibot);
            
            logEntry(app, app.logfilename, "Excalibot Initialised");
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%    UR  %%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position UR%%%%%%%%%
            % translation part
            position_UR = [-1.3134, -0.61995, 0.58111]; % x, y, z coordinates

            % rotation part
            roll_UR = deg2rad(0); % Roll (rotation about x-axis)
            pitch_UR = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_UR = deg2rad(180); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_UR = transl(position_UR) * rpy2tr(roll_UR, pitch_UR, yaw_UR);

            %%%%%%%%%% Create an instance of the URx class %%%%%%%%
            app.robot_UR = UR5(baseTr_UR);
            logEntry(app, app.logfilename, "UR5 Initialised");

            %%%%Excalibot starting pose %%%%%
            [startintgJointAngleExcalibot, ~, ~, ~, ~,~] = calculateMotion(app, app.robot_excalibot, [0.7788,0.2714,1.3423], [0,0,0]);
            app.robot_excalibot.model.animate(startintgJointAngleExcalibot);
            drawnow();
            logEntry(app, app.logfilename, "Excalibot Starting Position Set");

            %%Ur5 starting position%%
            [startintgJointAngleUR, ~, ~, ~, ~,~] = calculateMotion(app, app.robot_UR, [-1.7,-0.4999,1.4], [0,0,0]);
            % startintgJointAngleUR = startintgJointAngleUR +
            % [-pi/2,0,0,0,0,0];
            app.robot_UR.model.animate(startintgJointAngleUR);
            drawnow();
            logEntry(app, app.logfilename, "UR5 Starting Position Set");

            %%%%%%%%Initialize slider control%%%%%%%%%%
            % Set initial robot as Excalibot
            app.CurrentRobot = app.robot_excalibot;

            % Initialize sliders with Excalibot's position
            updateSliders(app, app.CurrentRobot);

            %%%%Initialize Lights%%%%%%
            updateLights(app);

            %%% Program Option
            app.selectedProgram = "Main";
            
            logEntry(app, app.logfilename, "GUI settings initialised");
            
            app.programRunning = false;
            app.holdingSword = false;
            app.robotMoving = false;
            app.updateCamera = false;

            Ur5Pose = app.robot_UR.model.fkine(app.robot_UR.model.getpos());
            app.camera = Camera(app.environment, [1, 1, 1], eye(3));
            app.cameraOffset = [0; 0; 0.1];

            app.tempFigureHandle = figure('Visible', 'on');
            app.tempAxesHandle = copyobj(app.environment.envAxes, app.tempFigureHandle);
            
            
            T = Ur5Pose.T;
            R = T(1:3, 1:3);
            app.camera = app.camera.setPosition(transl(Ur5Pose)+(R * app.cameraOffset)');
            app.camera = app.camera.setOrientation(R*rotx(deg2rad(90))*roty(deg2rad(0))*rotz(deg2rad(0)));
            app.camera.captureAndDisplay(app.tempAxesHandle,app.environment.envAxes);

            logEntry(app, app.logfilename, "Camera Initialised");
            logEntry(app, app.logfilename, "Selected Robot QuenchBot");
            logEntry(app, app.logfilename, sprintf('Program Selected: %s', app.selectedProgram));

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.serialObj);
            logEntry(app, app.logfilename, "Session Ended");
            delete(app)
            clc; close all;
        end

        % Value changed function: IgnitionQBotSwitch
        function IgnitionQBotSwitchValueChanged(app, event)
            updateLights(app);
            logEntry(app, app.logfilename, sprintf('IgnitionQBotSwitchValueChanged: %s', app.IgnitionQBotSwitch.Value));
        end

        % Value changed function: IgnitionEbotSwitch
        function IgnitionEbotSwitchValueChanged(app, event)
            updateLights(app);
            logEntry(app, app.logfilename, sprintf('IgnitionEbotSwitchValueChanged: %s', app.IgnitionEbotSwitch.Value));
        end

        % Selection changed function: AxisControlButtonGroup
        function AxisControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisControlButtonGroup.SelectedObject;

            if selectedButton == app.QuenchBotButton
                logEntry(app, app.logfilename, "Selected Robot QuenchBot");
                % QuenchBot (Excalibot) selected
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);
            elseif selectedButton == app.EvaluateBotButton
                logEntry(app, app.logfilename, "Selected Robot EvaluateBot");
                % EvaluateBot (UR5) selected
                updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
                app.CurrentRobot = app.robot_UR;  % Store the selected robot
                updateLights(app);
            end

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: USlider
        function USliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: VSlider
        function VSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: WSlider
        function WSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            updateLights(app);
            logEntry(app, app.logfilename, sprintf('EmergencyButtonValueChanged: %s', mat2str(app.EmergencyButton.Value)));
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;  % Get the key that was pressed

            % Check if the pressed key is 'space'
            if strcmp(key, 'space')
                app.EmergencyButton.Value = ~app.EmergencyButton.Value;
                logEntry(app, app.logfilename, sprintf('EmergencyButtonValueChanged: %s', mat2str(app.EmergencyButton.Value)));
                updateLights(app);
            end

        end

        % Selection changed function: ProgramSelectButtonGroup
        function ProgramSelectButtonGroupSelectionChanged(app, event)
            app.selectedProgram = app.ProgramSelectButtonGroup.SelectedObject.Text;
            logEntry(app, app.logfilename, sprintf('Program Selected: %s', app.selectedProgram));
        end

        % Button pushed function: StartProgramButton
        function StartProgramButtonPushed(app, event)
            if app.EmergencyButton.Value == false

                if (~app.programRunning) && (~app.robotMoving)
                    app.programRunning = true;
                    switch app.selectedProgram
                        case "Main"
                            logEntry(app, app.logfilename, "Main Program started");
                            MainProgram(app);
                            logEntry(app, app.logfilename, "Main Program ended");
                            app.programRunning = false;
                        case "Pickup"
                            logEntry(app, app.logfilename, "Pickup Program started");
                            MoveRobotToSword(app);
                            logEntry(app, app.logfilename, "Pickup Program ended");
                            app.programRunning = false;
                        case "Quench"
                            logEntry(app, app.logfilename, "Quench Program started");
                            MoveToWater(app);
                            logEntry(app, app.logfilename, "Quench Program ended");
                            app.programRunning = false;
                        case "Evaluate"
                            logEntry(app, app.logfilename, "Evaluate Program started");
                            MoveToInspection(app);
                            MoveCOBOTtoInspection(app);
                            logEntry(app, app.logfilename, "Evaluate Program ended");
                            app.programRunning = false;
                        case "Dispose"
                            logEntry(app, app.logfilename, "Dispose Program started");
                            MoveToDropOff(app);
                            logEntry(app, app.logfilename, "Dispose Program ended");
                            app.programRunning = false;
                    end
                else
                    logEntry(app, app.logfilename, sprintf('Program Could not Start: %s Program Running: %s Robot Moving: %s', app.selectedProgram, mat2str(app.programRunning), mat2str(app.robotMoving)));
                end

            else
                logEntry(app, app.logfilename, sprintf('Program Could not Start: %s Estop: %s', app.selectedProgram, mat2str(app.EmergencyButton.Value)));
            end
        end

        % Button pushed function: RestartSwordButton
        function RestartSwordButtonPushed(app, event)
            % Define the desired sword spawn position (1.1275, 0.51221, 0.63283)
            swordSpawnPosition = transl(1.1275, 0.51221, 0.63283);  % Translation matrix for sword position
            % Apply the translation to the sword vertices
            swordVerticesTransformed = [app.environment.sword.vertices, ones(size(app.environment.sword.vertices, 1), 1)] * swordSpawnPosition';  % Apply the translation
            set(app.environment.sword.patch, 'Vertices', swordVerticesTransformed(:, 1:3));
            
            app.holdingSword = false;
            % Redraw the plot
            drawnow();
            logEntry(app, app.logfilename, "Sword Position Reset");
        end

        % Value changed function: ViewMeshButton
        function ViewMeshButtonValueChanged(app, event)
            value = app.ViewMeshButton.Value;
            if value == 1
                load("reachablePointsExcalibot_far.mat","reachablePointsExcalibot");
                load("reachablePointsUR_far.mat","reachablePointsUR");
                load("AccuratePointsExcalibot_far.mat","accuratePointsExcalibot");
                load("AccuratePointsUR_far.mat","accuratePointsUR");
                resolution = 0.2;  % 5 cm resolution

                % Use alphaShape to create a mesh representing the volume of reachable points
                shpUR = alphaShape(reachablePointsUR(:,1), reachablePointsUR(:,2), reachablePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed
                accurateShpUR = alphaShape(accuratePointsUR(:,1), accuratePointsUR(:,2), accuratePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed
                shpExcalibot = alphaShape(reachablePointsExcalibot(:,1), reachablePointsExcalibot(:,2), reachablePointsExcalibot(:,3), resolution); % 1 is the alpha radius, adjust as needed
                accurateShpExcalibot = alphaShape(accuratePointsExcalibot(:,1), accuratePointsExcalibot(:,2), accuratePointsExcalibot(:,3), resolution);

                % Plot the alpha shape
                app.shpExcalibotplot = plot(shpExcalibot,'Parent',app.environment.envAxes);
                app.accurateShpExcalibotPlot = plot(accurateShpExcalibot,'Parent',app.environment.envAxes);
                % Set the color and transparency properties
                app.shpExcalibotplot.FaceColor = [0.8, 0.4, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                app.shpExcalibotplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                app.shpExcalibotplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

                % Set the color and transparency properties
                app.accurateShpExcalibotPlot.FaceColor = 'red';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                app.accurateShpExcalibotPlot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                app.accurateShpExcalibotPlot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)


                % Plot the alpha shape
                app.shpURplot = plot(shpUR,'Parent',app.environment.envAxes);
                app.accurateShpURplot = plot(accurateShpUR,'Parent',app.environment.envAxes);
                % Set the color and transparency properties
                app.shpURplot.FaceColor = [0.4, 0.7, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                app.shpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                app.shpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

                % Set the color and transparency properties
                app.accurateShpURplot.FaceColor = 'green';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                app.accurateShpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                app.accurateShpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)
            
                app.environment.plotBoundingBoxes();
            else
                 if ~isempty(app.shpExcalibotplot)
                    delete(app.shpExcalibotplot);  
                 end
                 if ~isempty(app.accurateShpExcalibotPlot)
                    delete(app.accurateShpExcalibotPlot);  
                 end
                 if ~isempty(app.shpURplot)
                    delete(app.shpURplot);  
                 end
                 if ~isempty(app.accurateShpURplot)
                    delete(app.accurateShpURplot);  
                 end
                 app.environment.removeBoundingBoxes()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.AxisControlButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisControlButtonGroupSelectionChanged, true);
            app.AxisControlButtonGroup.Title = 'AxisControl';
            app.QuenchBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.QuenchBotButton.Text = 'QuenchBot';
            app.QuenchBotButton.Value = true;
            app.EvaluateBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.EvaluateBotButton.Text = 'EvaluateBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ProgramSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ProgramSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramSelectButtonGroupSelectionChanged, true);
            app.ProgramSelectButtonGroup.Title = 'ProgramSelect';
            app.MainButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.PickupButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.PickupButton.Text = 'Pickup';
            app.QuenchButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.QuenchButton.Text = 'Quench';
            app.EvaluateButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.EvaluateButton.Text = 'Evaluate';
            app.DisposeButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.DisposeButton.Text = 'Dispose';
            app.IdleLampLabel = uilabel(app.UIFigure);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.UIFigure);
            app.USliderLabel = uilabel(app.UIFigure);
            app.USliderLabel.Text = 'U';
            app.USlider = uislider(app.UIFigure);
            app.USlider.Limits = [-180 180];
            app.USlider.ValueChangedFcn = createCallbackFcn(app, @USliderValueChanged, true);
            app.OperatingLampLabel = uilabel(app.UIFigure);
            app.OperatingLampLabel.Text = 'Operating';
            app.OperatingLamp = uilamp(app.UIFigure);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [-180 180];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VSliderValueChanged, true);
            app.StartProgramButton = uibutton(app.UIFigure, 'push');
            app.StartProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StartProgramButtonPushed, true);
            app.StartProgramButton.Text = 'StartProgram';
            app.MovementSpeedQuenchBotGauge = uigauge(app.UIFigure, 'linear');
            app.WSliderLabel = uilabel(app.UIFigure);
            app.WSliderLabel.Text = 'W';
            app.WSlider = uislider(app.UIFigure);
            app.WSlider.Limits = [-180 180];
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @WSliderValueChanged, true);
            app.EmergencyButton = uibutton(app.UIFigure, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Icon = fullfile(pathToMLAPP, 'emergency-stop-warning-sign-s-1182.png');
            app.EmergencyButton.Text = '';
            app.IgnitionQBotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionQBotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionQBotSwitchValueChanged, true);
            app.IgnitionEbotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionEbotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionEbotSwitchValueChanged, true);
            app.MovementSpeedQuenchBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedQuenchBotGaugeLabel.Text = 'MovementSpeedQuenchBot';
            app.MovementSpeedEvaluateBotGauge = uigauge(app.UIFigure, 'linear');
            app.IgnitionQBotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionQBotSwitchLabel.Text = 'IgnitionQBot';
            app.IgnitionEbotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionEbotSwitchLabel.Text = 'IgnitionEbot';
            app.MovementSpeedEvaluateBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedEvaluateBotGaugeLabel.Text = 'MovementSpeedEvaluateBot';
            app.RestartSwordButton = uibutton(app.UIFigure, 'push');
            app.RestartSwordButton.ButtonPushedFcn = createCallbackFcn(app, @RestartSwordButtonPushed, true);
            app.RestartSwordButton.Text = 'RestartSword';
            app.ViewMeshButton = uibutton(app.UIFigure, 'state');
            app.ViewMeshButton.ValueChangedFcn = createCallbackFcn(app, @ViewMeshButtonValueChanged, true);
            app.ViewMeshButton.Text = 'ViewMesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end